classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        STARTMEASSUEREButton      matlab.ui.control.Button
        SETACCELSM303AGRButton    matlab.ui.control.Button
        SETWHEATHERButton         matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        AccelometerLSM6DSLTab     matlab.ui.container.Tab
        ACCEZTextArea             matlab.ui.control.TextArea
        ACCEZTextAreaLabel        matlab.ui.control.Label
        ACCEYTextArea             matlab.ui.control.TextArea
        ACCEYTextAreaLabel        matlab.ui.control.Label
        ACCEXTextArea             matlab.ui.control.TextArea
        ACCEXTextAreaLabel        matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        AccelometerLSM303AGRTab   matlab.ui.container.Tab
        ACCEZTextArea_303AGR      matlab.ui.control.TextArea
        ACCEZTextArea_2Label      matlab.ui.control.Label
        ACCEYTextArea_303AGR      matlab.ui.control.TextArea
        ACCEYTextArea_2Label      matlab.ui.control.Label
        ACCEXTextArea_303AGR      matlab.ui.control.TextArea
        ACCEXTextArea_2Label      matlab.ui.control.Label
        UIAxes1                   matlab.ui.control.UIAxes
        TemperatureTab            matlab.ui.container.Tab
        HUMIDITYTextArea          matlab.ui.control.TextArea
        HUMIDITYTextAreaLabel     matlab.ui.control.Label
        TEMPLPS22HBTextArea       matlab.ui.control.TextArea
        TEMPLPS22HBTextAreaLabel  matlab.ui.control.Label
        PREASURETextArea          matlab.ui.control.TextArea
        PREASURETextAreaLabel     matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        STOPButton                matlab.ui.control.Button
        HUMIDITYGauge             matlab.ui.control.SemicircularGauge
        HUMIDITYGaugeLabel        matlab.ui.control.Label
        TEMPLPS22HBGauge          matlab.ui.control.Gauge
        TEMPLPS22HBGaugeLabel     matlab.ui.control.Label
        PREASUREGauge             matlab.ui.control.Gauge
        PREASUREGaugeLabel        matlab.ui.control.Label
        LEDOFFONSwitch            matlab.ui.control.Switch
        LEDOFFONSwitchLabel       matlab.ui.control.Label
        STM32GUILabel             matlab.ui.control.Label
        STATUSLEDLamp             matlab.ui.control.Lamp
        STATUSLEDLampLabel        matlab.ui.control.Label
        SETACCELSM6DSLButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        s = 0;          %%serialhandle
        num_sample = 0; %%num of sample
        d, data;
        string;string1;
        is_on = false;     %%bool val when working program
        startTime = 0;
        data1,data2,data3,data4,data5,data6, data7, data8, data9;
        h,j,k,l,m,n,o,p;
        is_set_LSM303AGR= 0, is_set_LSM6DSL = 0, is_set_WHEATHER  = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all; clc;
            app.s = serialport("COM4",115200); %%open serial port 
          
            app.h = animatedline(app.UIAxes, 'Color', 'r', 'LineWidth', 2);  %%create animated line
            app.j = animatedline(app.UIAxes, 'Color', 'b', 'LineWidth', 2);  %%create animated line
            app.k = animatedline(app.UIAxes, 'Color', 'g', 'LineWidth', 2);  %%create animated line
            
            app.l = animatedline(app.UIAxes1, 'Color', 'r', 'LineWidth', 2);  %%create animated line
            app.m = animatedline(app.UIAxes1, 'Color', 'b', 'LineWidth', 2);  %%create animated line
            app.n = animatedline(app.UIAxes1, 'Color', 'g', 'LineWidth', 2);  %%create animated line

            app.o = animatedline(app.UIAxes2, 'Color', 'r', 'LineWidth', 2);  %%create animated line
          
            app.PREASUREGauge.Limits = [900 1100];
              
        end

        % Button pushed function: SETACCELSM6DSLButton
        function SETACCELSM6DSLButtonPushed(app, event)
          
            app.is_set_LSM6DSL = 1;
                      
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.is_on = false;
            clear all;
        end

        % Value changed function: LEDOFFONSwitch
        function LEDOFFONSwitchValueChanged(app, event)
            value = app.LEDOFFONSwitch.Value;
            
            if(value == "On")
                value = 0b00000001;
                app.STATUSLEDLamp.Color = 'g';
            else
              value = 0b00000000;
              app.STATUSLEDLamp.Color = 'r';
              
            end
            write(app.s, value, "uint8");
        end

        % Button pushed function: SETACCELSM303AGRButton
        function SETACCELSM303AGRButtonPushed(app, event)
              app.is_set_LSM303AGR = 1;
        end

        % Button pushed function: SETWHEATHERButton
        function SETWHEATHERButtonPushed(app, event)
           app.is_set_WHEATHER = 1;
        end

        % Button pushed function: STARTMEASSUEREButton
        function STARTMEASSUEREButtonPushed(app, event)
            app.is_on = true;
            app.startTime = datetime('now');  %%take start time, button push
            %%app.num_sample = app.LiczbaprbekEditField.Value();
            while(app.is_on ~= false)
                app.d=read(app.s,200,"char");
                if(strfind(app.d,'FFF'))
                    [app.string, app.string1] = strtok(app.d,"FFF");
                    app.string = app.string1(:,1:100);
                    app.data = sscanf(app.string,"FFF%f %f %f %f %f %f %f %f %f\n\r");
                end
                app.data1 = app.data(1);
                app.data2 = app.data(2);
                app.data3 = app.data(3);
                app.data4 = app.data(4);
                app.data5 = app.data(5);
                app.data6 = app.data(6);
                app.data7 = app.data(7);
                app.data8 = app.data(8);
                app.data9 = app.data(9);
                t = datetime('now') - app.startTime;
                if(app.is_set_LSM6DSL == 1) %%draw LSM6DSL
                    addpoints(app.h, datenum(t), app.data1);
                    addpoints(app.j, datenum(t), app.data2);
                    addpoints(app.k, datenum(t), app.data3);  
                    app.ACCEXTextArea.Value = num2str(app.data1);
                    app.ACCEYTextArea.Value = num2str(app.data2);
                    app.ACCEZTextArea.Value = num2str(app.data3);
                    app.UIAxes.XLim = datenum([t-seconds(15) t]);
                    app.UIAxes.YLim = [-4 4];
                end
                if(app.is_set_LSM303AGR == 1) %%draw LSM303AGR
                    addpoints(app.l, datenum(t), app.data4);
                    addpoints(app.m, datenum(t), app.data5);
                    addpoints(app.n, datenum(t), app.data6);  
                    app.ACCEXTextArea_303AGR.Value = num2str(app.data4);
                    app.ACCEYTextArea_303AGR.Value = num2str(app.data5);
                    app.ACCEZTextArea_303AGR.Value = num2str(app.data6);
                    app.UIAxes1.XLim = datenum([t-seconds(15) t]);
                    app.UIAxes1.YLim = [-4 4];
                end
                if(app.is_set_WHEATHER == 1) %%draw LSM303AGR
                      addpoints(app.o, datenum(t), app.data8);
             
                      app.PREASURETextArea.Value = num2str(app.data7);
                      app.TEMPLPS22HBTextArea.Value = num2str(app.data8);
                      app.HUMIDITYTextArea.Value = num2str(app.data9);
                      app.PREASUREGauge.Value = app.data7;
                     % app.PREASUREGauge.Limits = [900 1100];
                      app.TEMPLPS22HBGauge.Value  = app.data8;
                      app.HUMIDITYGauge.Value = app.data9;
                     % app.UIAxes.XLim = datenum([t-seconds(15) t]);
                     % app.UIAxes.YLim = [-4 4];
                end


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SETACCELSM6DSLButton = uibutton(app.UIFigure, 'push');
            app.SETACCELSM6DSLButton.ButtonPushedFcn = createCallbackFcn(app, @SETACCELSM6DSLButtonPushed, true);
            app.SETACCELSM6DSLButton.Text = {'SET'; 'ACCE LSM6DSL'};
            app.STATUSLEDLampLabel = uilabel(app.UIFigure);
            app.STATUSLEDLampLabel.Text = 'STATUS LED';
            app.STATUSLEDLamp = uilamp(app.UIFigure);
            app.STM32GUILabel = uilabel(app.UIFigure);
            app.STM32GUILabel.Text = 'STM32  GUI';
            app.LEDOFFONSwitchLabel = uilabel(app.UIFigure);
            app.LEDOFFONSwitchLabel.Text = 'LED OFF/ON';
            app.LEDOFFONSwitch = uiswitch(app.UIFigure, 'slider');
            app.LEDOFFONSwitch.ValueChangedFcn = createCallbackFcn(app, @LEDOFFONSwitchValueChanged, true);
            app.PREASUREGaugeLabel = uilabel(app.UIFigure);
            app.PREASUREGaugeLabel.Text = 'PREASURE';
            app.PREASUREGauge = uigauge(app.UIFigure, 'circular');
            app.TEMPLPS22HBGaugeLabel = uilabel(app.UIFigure);
            app.TEMPLPS22HBGaugeLabel.Text = 'TEMP LPS22HB';
            app.TEMPLPS22HBGauge = uigauge(app.UIFigure, 'circular');
            app.HUMIDITYGaugeLabel = uilabel(app.UIFigure);
            app.HUMIDITYGaugeLabel.Text = 'HUMIDITY';
            app.HUMIDITYGauge = uigauge(app.UIFigure, 'semicircular');
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AccelometerLSM6DSLTab = uitab(app.TabGroup);
            app.AccelometerLSM6DSLTab.Title = 'Accelometer LSM6DSL';
            app.UIAxes = uiaxes(app.AccelometerLSM6DSLTab);
            title(app.UIAxes, 'ACCELOMETTER LSM6DSL  VALUE in G')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Value[m/s^2]')
            zlabel(app.UIAxes, 'Z')
            app.ACCEXTextAreaLabel = uilabel(app.AccelometerLSM6DSLTab);
            app.ACCEXTextAreaLabel.Text = 'ACCE X:';
            app.ACCEXTextArea = uitextarea(app.AccelometerLSM6DSLTab);
            app.ACCEYTextAreaLabel = uilabel(app.AccelometerLSM6DSLTab);
            app.ACCEYTextAreaLabel.Text = 'ACCE Y:';
            app.ACCEYTextArea = uitextarea(app.AccelometerLSM6DSLTab);
            app.ACCEZTextAreaLabel = uilabel(app.AccelometerLSM6DSLTab);
            app.ACCEZTextAreaLabel.Text = 'ACCE Z:';
            app.ACCEZTextArea = uitextarea(app.AccelometerLSM6DSLTab);
            app.AccelometerLSM303AGRTab = uitab(app.TabGroup);
            app.AccelometerLSM303AGRTab.Title = 'Accelometer LSM303AGR';
            app.UIAxes1 = uiaxes(app.AccelometerLSM303AGRTab);
            title(app.UIAxes1, 'ACCELOMETTER LSM303AGR  VALUE in G')
            xlabel(app.UIAxes1, 'Time')
            ylabel(app.UIAxes1, 'Value[m/s^2]')
            zlabel(app.UIAxes1, 'Z')
            app.ACCEXTextArea_2Label = uilabel(app.AccelometerLSM303AGRTab);
            app.ACCEXTextArea_2Label.Text = 'ACCE X:';
            app.ACCEXTextArea_303AGR = uitextarea(app.AccelometerLSM303AGRTab);
            app.ACCEYTextArea_2Label = uilabel(app.AccelometerLSM303AGRTab);
            app.ACCEYTextArea_2Label.Text = 'ACCE Y:';
            app.ACCEYTextArea_303AGR = uitextarea(app.AccelometerLSM303AGRTab);
            app.ACCEZTextArea_2Label = uilabel(app.AccelometerLSM303AGRTab);
            app.ACCEZTextArea_2Label.Text = 'ACCE Z:';
            app.ACCEZTextArea_303AGR = uitextarea(app.AccelometerLSM303AGRTab);
            app.TemperatureTab = uitab(app.TabGroup);
            app.TemperatureTab.Title = 'Temperature';
            app.UIAxes2 = uiaxes(app.TemperatureTab);
            title(app.UIAxes2, 'Temp value')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Temp in *C')
            zlabel(app.UIAxes2, 'Z')
            app.PREASURETextAreaLabel = uilabel(app.TemperatureTab);
            app.PREASURETextAreaLabel.Text = 'PREASURE';
            app.PREASURETextArea = uitextarea(app.TemperatureTab);
            app.TEMPLPS22HBTextAreaLabel = uilabel(app.TemperatureTab);
            app.TEMPLPS22HBTextAreaLabel.Text = 'TEMP LPS22HB';
            app.TEMPLPS22HBTextArea = uitextarea(app.TemperatureTab);
            app.HUMIDITYTextAreaLabel = uilabel(app.TemperatureTab);
            app.HUMIDITYTextAreaLabel.Text = 'HUMIDITY';
            app.HUMIDITYTextArea = uitextarea(app.TemperatureTab);
            app.SETWHEATHERButton = uibutton(app.UIFigure, 'push');
            app.SETWHEATHERButton.ButtonPushedFcn = createCallbackFcn(app, @SETWHEATHERButtonPushed, true);
            app.SETWHEATHERButton.Text = {'SET'; 'WHEATHER '};
            app.SETACCELSM303AGRButton = uibutton(app.UIFigure, 'push');
            app.SETACCELSM303AGRButton.ButtonPushedFcn = createCallbackFcn(app, @SETACCELSM303AGRButtonPushed, true);
            app.SETACCELSM303AGRButton.Text = {'SET'; 'ACCE LSM303AGR'};
            app.STARTMEASSUEREButton = uibutton(app.UIFigure, 'push');
            app.STARTMEASSUEREButton.ButtonPushedFcn = createCallbackFcn(app, @STARTMEASSUEREButtonPushed, true);
            app.STARTMEASSUEREButton.Text = {'START'; 'MEASSUERE'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end